classdef robot_spectroelectrochemistry_Abs_chrono_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ChronoamperometryPanel          matlab.ui.container.Panel
        UIAxes3_4                       matlab.ui.control.UIAxes
        UVVisSpectroelectrochemistryDedopingPanel  matlab.ui.container.Panel
        UIAxes2_2                       matlab.ui.control.UIAxes
        ChronoamperometryDedopingPanel  matlab.ui.container.Panel
        UIAxes3_3                       matlab.ui.control.UIAxes
        ChronoamperometryDopingPanel    matlab.ui.container.Panel
        UIAxes3_2                       matlab.ui.control.UIAxes
        StatusPanel                     matlab.ui.container.Panel
        SampleNumberEditField           matlab.ui.control.EditField
        SampleNumberEditFieldLabel      matlab.ui.control.Label
        CurrentElectrolyteEditField     matlab.ui.control.EditField
        CurrentElectrolyteEditFieldLabel  matlab.ui.control.Label
        CurrentPolymerEditField         matlab.ui.control.EditField
        CurrentPolymerEditFieldLabel    matlab.ui.control.Label
        CyclicVoltammetryPanel          matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        UVVisSpectroelectrochemistryDopingPanel  matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        TextArea                        matlab.ui.control.TextArea
        RobotStatusLabel                matlab.ui.control.Label
        ControlPanel                    matlab.ui.container.Panel
        CollectSpectrumButton           matlab.ui.control.Button
        StartRobotButton                matlab.ui.control.Button
        ConnectionsPanel                matlab.ui.container.Panel
        DisconnectButton_5              matlab.ui.control.Button
        ConnecttoPotentiostatButton     matlab.ui.control.Button
        DisconnectButton_4              matlab.ui.control.Button
        DisconnectButton                matlab.ui.control.Button
        ConnecttoSpectrometerButton     matlab.ui.control.Button
        ConnecttoArduinoButton          matlab.ui.control.Button
        UIAxes4_5                       matlab.ui.control.UIAxes
        UIAxes4_4                       matlab.ui.control.UIAxes
        UIAxes4_3                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        a % arduino COM4
        deviceName % Description
        comm % Description
        m
        method
        measurement % Description
        spectrometerObj % Description
        darkspectrum % Description
        lampspectrum % Description
        b % arduino COM7
        servo_door % Description
        servo_spinner %
        servo_stopper % Description
        color1 % First color used for plotting
        color2 % Second color used for plotting
        color_gradient % Color gradient
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnecttoArduinoButton
        function ConnecttoArduinoButtonPushed(app, event)
            set(app.ConnecttoArduinoButton, 'Enable', 'off')

            try
                app.a=arduino('COM5')
                app.TextArea.Value = [app.TextArea.Value' 'Arduino COM4 connected']
                scroll(app.TextArea, 'bottom')
                set(app.LEDOnButton, 'Enable', 'on')
                
                %app.b=arduino('COM7')
                %app.TextArea.Value = [app.TextArea.Value' 'Arduino COM7 connected']
                %scroll(app.TextArea, 'bottom')
            catch
                app.TextArea.Value =[app.TextArea.Value' 'Arduino did not connect. You may have to restart the program.']
                scroll(app.TextArea, 'bottom')
            end


            set(app.DisconnectButton, 'Enable', 'on')
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            set(app.DisconnectButton, 'Enable', 'off')
            fclose(serial(app.a.Port))
            clear app.a
            set(app.ConnecttoArduinoButton, 'Enable', 'on')
            set(app.LEDOnButton, 'Enable', 'off')
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            app.LEDBrightnessSlider.Value = app.EditField.Value
            writePWMVoltage(app.a, 'D9', app.LEDBrightnessSlider.Value/100*5)
        end

        % Callback function
        function ConnecttoPotentiostatButtonPushed(app, event)
            set(app.ConnecttoPotentiostatButton, 'Enable', 'off')

            cd 'C:\Users\cgbis\Desktop\palmsense_new'

            PSSDK = LoadPSSDK();

            app.TextArea.Value = 'Initiating program...';

            if(PSSDK == false)
                app.TextArea.Value = 'Error while adding the PalmSens SDK to the workspace. Please resolve before continuing.';
                scroll(app.TextArea, 'bottom')
                set(app.ConnecttoPotentiostatButton, 'Enable', 'on')
                return;
            end

            deviceList = GetConnectedDevices(); %see function help for scanning bluetooth and serial devices
            nDevices = deviceList.Count; %number of found devices
            app.TextArea.Value = ([app.TextArea.Value' newline num2str(nDevices) ' device(s) connected:']);
            scroll(app.TextArea, 'bottom')

            %Display connected devices
            for i=0:nDevices-1
                disp([num2str(i+1) '. ' char(deviceList.Item(i).ToString())]);
            end

            %Prompt device to open a connection to
            selectedDevice = 1;

            %Open a connection to the specified device
            selectedDevice = selectedDevice - 1;
            app.deviceName = char(deviceList.Item(selectedDevice).ToString())
            app.TextArea.Value = ([app.TextArea.Value' 'Attempting to open a connection with ' app.deviceName newline]);
            scroll(app.TextArea, 'bottom')
            app.comm = OpenConnection(deviceList.Item(selectedDevice));
            if(app.comm == false) %Check whether the connection was succesfully opened
                app.TextArea.Value = ([app.TextArea.Value' 'Error while opening a connection to ' app.deviceName '. Please check whether the specified device' newline ' is a PalmSens or Emstat device, remove the usb and plug it back in, and try to open the connection again' newline]);
                scroll(app.TextArea, 'bottom')
                set(app.ConnecttoPotentiostatButton, 'Enable', 'on')
                return;
            end


            app.TextArea.Value = ([app.TextArea.Value' 'Succesfully connected to ' app.deviceName]);
            scroll(app.TextArea, 'bottom')

            set(app.DisconnectButton_3, 'Enable', 'on')
            set(app.StartRobotButton, 'Enable', 'on')
        end

        % Callback function
        function DisconnectButton_3Pushed(app, event)
            set(app.ConnecttoPotentiostatButton, 'Enable', 'on')

            app.TextArea.Value = ([app.TextArea.Value' newline 'Attempting to disconnect from ' app.deviceName]);
            scroll(app.TextArea, 'bottom')
            try
                app.comm.Disconnect();
                app.TextArea.Value = ([app.TextArea.Value' newline 'Succesfully closed the connection to ' app.deviceName]);
                scroll(app.TextArea, 'bottom')
            catch
                app.TextArea.Value = ([app.TextArea.Value' newline 'An error occured while trying to disconnect from ' app.deviceName]);
                scroll(app.TextArea, 'bottom')
            end

            set(app.DisconnectButton_3, 'Enable', 'off')
            set(app.StartRobotButton, 'Enable', 'off')
        end

        % Button pushed function: StartRobotButton
        function StartRobotButtonPushed(app, event)
            % Load CSV file with sample parameters

            cd 'C:\Users\cgbis\Desktop'
            sample_list = uigetfile('*.csv')
            data = table2struct(readtable(sample_list))

            % Define number of samples

            Nsamples = length(data);

            Nspectra = 100;

            Nsamples_statement = sprintf('The number of samples is %d.', Nsamples);

            app.TextArea.Value = ([app.TextArea.Value' newline Nsamples_statement])
            scroll(app.TextArea, 'bottom')

            writePWMVoltage(app.a, 'D9', 0)

            configurePin(app.a,'D7','DigitalInput')

            writeDigitalPin(app.a,'D6',1)
            app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Sent'])
            scroll(app.TextArea, 'bottom')
            pause(5)
            writeDigitalPin(app.a,'D6',0)
            app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Off'])
            scroll(app.TextArea, 'bottom')

            pause(15)

            cam2 = webcam(2);
            img = [];
            counter = 0;

            while readDigitalPin(app.a,'D7') < 1 && counter <= 1800
                countertext = sprintf('Looking for Signal from Robot (Counter at %d)', counter)
                app.TextArea.Value = ([app.TextArea.Value' newline countertext])
                scroll(app.TextArea, 'bottom')
                pause(0.03)
                img1 = snapshot(cam2);
                img = cat(2,img,img1);
                counter = counter + 1;
            end

            if counter > 1800
                ImageLength = length(img);
                ImageFrameCount = ImageLength / 640;
                ImageFrames = cell(ImageFrameCount,1);
                for n = 1:ImageFrameCount
                    ImageFrames{n} = img(:,(n-1)*640+1:n*640,:);
                end
                writerObj = VideoWriter(sprintf('RobotFailVideo %s',today('datetime')),'MPEG-4');
                writerObj.FrameRate = 10;
                open(writerObj);
                for z = 1:length(ImageFrames)
                    frame = im2frame(ImageFrames{z});
                    for x = 0:0.1
                        writeVideo(writerObj,frame);
                    end
                end
                close(writerObj);
                answer = questdlg('GAME OVER?','Yes','No');
            end

            clear img
            clear counter

            app.TextArea.Value = ([app.TextArea.Value' newline 'Received Signal from Robot'])
            scroll(app.TextArea, 'bottom')

            answer = questdlg('Did the robot gripper open and close?', ...
                'Yes','No');

            if strcmpi(answer, 'Yes')
                writeDigitalPin(app.a,'D6',1)
                app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Sent'])
                scroll(app.TextArea, 'bottom')
                pause(5)
                writeDigitalPin(app.a,'D6',0)
                app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Off'])
                scroll(app.TextArea, 'bottom')
            else
                return
            end

            app.color1 = hex2rgb('#CC000'); % First color of gradient
            app.color2 = hex2rgb('#D0D0D0'); % Second color of gradient
            app.color_gradient = @(i,N) app.color1 + (app.color2-app.color1)*((i-1)/(N-1)); % Create a color gradient

            setappdata(0,'AppStartTime',tic); % Starts measuring time elapsed at the beginning of the experiment

            writeDigitalPin(app.a,'D11',0) % close shutter on UV-Vis lamp
            for n=1:Nsamples

                current_sample = data(n).Sample;
                current_polymer = data(n).Polymer;
                current_electrolyte = data(n).Electrolyte;

                app.SampleNumberEditField.Value = num2str(current_sample);
                app.CurrentElectrolyteEditField.Value = current_electrolyte;
                app.CurrentPolymerEditField.Value = current_polymer;

                img = [];
                counter = 0;

                while readDigitalPin(app.a,'D7') < 1 && counter <= 1800
                    countertext = sprintf('Looking for Signal from Robot (Counter at %d)', counter)
                    app.TextArea.Value = ([app.TextArea.Value' newline countertext])
                    scroll(app.TextArea, 'bottom')
                    pause(0.1)
                    img1 = snapshot(cam2);
                    img = cat(2,img,img1);
                    counter = counter + 1;
                end

                if counter > 1800
                    ImageLength = length(img);
                    ImageFrameCount = ImageLength / 640;
                    ImageFrames = cell(ImageFrameCount,1);
                    for n = 1:ImageFrameCount
                        ImageFrames{n} = img(:,(n-1)*640+1:n*640,:);
                    end
                    writerObj = VideoWriter(sprintf('RobotFailVideo %s',today('datetime')),'MPEG-4');
                    writerObj.FrameRate = 10;
                    open(writerObj);
                    for z = 1:length(ImageFrames)
                        frame = im2frame(ImageFrames{z});
                        for x = 0:0.1
                            writeVideo(writerObj,frame);
                        end
                    end
                    close(writerObj);
                    answer = questdlg('GAME OVER?','Yes','No');
                end

                clear img
                clear counter

                pause(15)

                % Collect spectrum for blank %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                app.TextArea.Value = ([app.TextArea.Value' newline 'Collecting data from BLANK!'])

                % Collect spectrum part

                writeDigitalPin(app.a,'D11',1) % Opens lamp shutter
                pause(0.1)

                % integration time for sensor.
                integrationTime = 50000;
                % Spectrometer index to use (first spectrometer by default).
                spectrometerIndex = 0;
                % Channel index to use (first channel by default).
                channelIndex = 0;
                % Enable flag.
                enable = 1;

                % Set integration time.
                invoke(app.spectrometerObj, 'setIntegrationTime', spectrometerIndex, channelIndex, integrationTime);
                % Enable correct for detector non-linearity.
                invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', spectrometerIndex, channelIndex, enable);
                % Enable correct for electrical dark.
                invoke(app.spectrometerObj, 'setCorrectForElectricalDark', spectrometerIndex, channelIndex, enable);

                wavelengths = invoke(app.spectrometerObj, 'getWavelengths', spectrometerIndex, channelIndex);
                % Get the wavelengths of the first spectrometer and save them in a double
                % array.

                spectralData_bg = 0;

                for q = 1:Nspectra

                    spectrum_temp = invoke(app.spectrometerObj, 'getSpectrum', spectrometerIndex);
                    spectralData_bg = spectralData_bg + spectrum_temp;

                end

                abs_spectrum = spectralData_bg;
                hold(app.UIAxes2, 'off')
                plot(app.UIAxes2,wavelengths,abs_spectrum)
                xlim(app.UIAxes2,[400 900])

                writeDigitalPin(app.a,'D11',0) % Closes lamp shutter

                writeDigitalPin(app.a,'D6',1)
                app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Sent'])
                scroll(app.TextArea, 'bottom')
                pause(5)
                writeDigitalPin(app.a,'D6',0)
                app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Off'])
                scroll(app.TextArea, 'bottom')

                % Take photo of sample before Abs experiment
                img = [];
                counter = 0;

                while readDigitalPin(app.a,'D7') < 1 && counter <= 1800
                    countertext = sprintf('Looking for Signal from Robot (Counter at %d)', counter)
                    app.TextArea.Value = ([app.TextArea.Value' newline countertext])
                    scroll(app.TextArea, 'bottom')
                    pause(0.1)
                    img1 = snapshot(cam2);
                    img = cat(2,img,img1);
                    counter = counter + 1;
                end

                if counter > 1800
                    ImageLength = length(img);
                    ImageFrameCount = ImageLength / 640;
                    ImageFrames = cell(ImageFrameCount,1);
                    for n = 1:ImageFrameCount
                        ImageFrames{n} = img(:,(n-1)*640+1:n*640,:);
                    end
                    writerObj = VideoWriter(sprintf('RobotFailVideo %s',today('datetime')),'MPEG-4');
                    writerObj.FrameRate = 10;
                    open(writerObj);
                    for z = 1:length(ImageFrames)
                        frame = im2frame(ImageFrames{z});
                        for x = 0:0.1
                            writeVideo(writerObj,frame);
                        end
                    end
                    close(writerObj);
                    answer = questdlg('GAME OVER?','Yes','No');
                end

                clear img
                clear counter

                app.TextArea.Value = ([app.TextArea.Value' newline 'Received Signal from Robot'])
                scroll(app.TextArea, 'bottom')

                pause(5)

                cam1 = webcam(1);
                cam1.Resolution = '1280x720';
                img = snapshot(cam1);
                rect = [465.5100    8.5100  458.9800  604.9800];

                img1=imcrop(img,rect);
                img3 = flipud(img1);

                data(n).imagebeforeAbs = img3;

                imshow(img3,'Parent',app.UIAxes4_3)

                pause(5)

                writeDigitalPin(app.a,'D6',1)
                app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Sent'])
                scroll(app.TextArea, 'bottom')
                pause(5)
                writeDigitalPin(app.a,'D6',0)
                app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Off'])
                scroll(app.TextArea, 'bottom')

                img = [];
                counter = 0;

                while readDigitalPin(app.a,'D7') < 1 && counter <= 1800
                    countertext = sprintf('Looking for Signal from Robot (Counter at %d)', counter)
                    app.TextArea.Value = ([app.TextArea.Value' newline countertext])
                    scroll(app.TextArea, 'bottom')
                    pause(0.1)
                    img1 = snapshot(cam2);
                    img = cat(2,img,img1);
                    counter = counter + 1;
                end

                if counter > 1800
                    ImageLength = length(img);
                    ImageFrameCount = ImageLength / 640;
                    ImageFrames = cell(ImageFrameCount,1);
                    for n = 1:ImageFrameCount
                        ImageFrames{n} = img(:,(n-1)*640+1:n*640,:);
                    end
                    writerObj = VideoWriter(sprintf('RobotFailVideo %s',today('datetime')),'MPEG-4');
                    writerObj.FrameRate = 10;
                    open(writerObj);
                    for z = 1:length(ImageFrames)
                        frame = im2frame(ImageFrames{z});
                        for x = 0:0.1
                            writeVideo(writerObj,frame);
                        end
                    end
                    close(writerObj);
                    answer = questdlg('GAME OVER?','Yes','No');
                end

                clear img
                clear counter

                % Take spectra of the sample %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                pause(5)

                app.TextArea.Value = ([app.TextArea.Value' newline 'Received Signal from Robot'])
                scroll(app.TextArea, 'bottom')

                app.TextArea.Value = ([app.TextArea.Value' newline 'Collecting Spectroelectrochemistry Data...'])
                scroll(app.TextArea, 'bottom')

                % Collect spectrum before experiment

                writeDigitalPin(app.a,'D11',1) % Opens lamp shutter
                pause(0.1)

                % integration time for sensor.
                integrationTime = 50000;
                % Spectrometer index to use (first spectrometer by default).
                spectrometerIndex = 0;
                % Channel index to use (first channel by default).
                channelIndex = 0;
                % Enable flag.
                enable = 1;

                % Set integration time.
                invoke(app.spectrometerObj, 'setIntegrationTime', spectrometerIndex, channelIndex, integrationTime);
                % Enable correct for detector non-linearity.
                invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', spectrometerIndex, channelIndex, enable);
                % Enable correct for electrical dark.
                invoke(app.spectrometerObj, 'setCorrectForElectricalDark', spectrometerIndex, channelIndex, enable);

                wavelengths = invoke(app.spectrometerObj, 'getWavelengths', spectrometerIndex, channelIndex);
                % Get the wavelengths of the first spectrometer and save them in a double
                % array.

                spectralData_ss = 0;

                for q = 1:Nspectra

                    spectrum_temp = invoke(app.spectrometerObj, 'getSpectrum', spectrometerIndex);
                    spectralData_ss = spectralData_ss + spectrum_temp;

                end

                abs_spectrum = -log(medfilt1(spectralData_ss)./medfilt1(spectralData_bg));

                hold(app.UIAxes2, 'off')
                plot(app.UIAxes2,wavelengths,abs_spectrum)
                xlim(app.UIAxes2,[400 900])

                data(n).wavelengths = wavelengths;
                data(n).abs_spectrum = abs_spectrum;

                writeDigitalPin(app.a,'D11',0) % Closes lamp shutter

                % RECORDING CVs

                cd 'C:\Users\cgbis\Desktop\palmsense_new'

                test = 1;

                if test == 1
                    app.method = PalmSens.Techniques.CyclicVoltammetry();
                    app.method.BeginPotential = data(n).CV_Start; %app.EquilibrationVoltageVEditField.Value;
                    app.method.EndPotential = data(n).CV_End; %app.EquilibrationVoltageVEditField.Value;
                    app.method.StepPotential = data(n).CV_Step; %app.StepSizeVEditField.Value;
                    app.method.Scanrate = data(n).CV_Rate; %app.SweepRateVsEditField.Value;
                    app.method.EquilibrationTime = data(n).CV_Equil_Time;
                    app.method.Vtx1Potential = data(n).CV_V1; %app.UpperVoltageVEditField.Value;
                    app.method.Vtx2Potential = data(n).CV_V2; %app.LowerVoltageEditField.Value;
                    total_time = app.method.EquilibrationTime+(app.method.Vtx1Potential-app.method.BeginPotential+app.method.BeginPotential-app.method.Vtx2Potential+app.method.Vtx1Potential-app.method.Vtx2Potential+app.method.EndPotential-data(n).CV_V2)/app.method.Scanrate;

                    app.m = Measurement(app.comm);
                    app.m.dispInCommandWindow = true; %Displays status information in the command window
                    app.m.dispInPlot = true; %Plots measurement data in realtime


                    % Equilibration

                    %Turn on the cell
                    app.comm.CellOn = true;
                    app.TextArea.Value = ([app.TextArea.Value' 'Cell is On'])
                    scroll(app.TextArea, 'bottom')

                    app.comm.Potential = data(n).CV_Equil_V;
                    app.TextArea.Value = ([app.TextArea.Value' 'Starting Equilibration']);
                    scroll(app.TextArea, 'bottom')
                    pause(data(n).CV_Equil_Time)
                    app.TextArea.Value = ([app.TextArea.Value' 'Equilibration Finished']);
                    scroll(app.TextArea, 'bottom')


                    for p=1:round(double(data(n).N_CV))

                        app.m.New(app.method)

                        while(app.m.inMeasurement) %pauses until measurement is over
                            pause(0.01);
                        end

                        app.measurement = app.m.measurement;

                        %preallocate the x & y axis and the legend
                        x = zeros(length(app.measurement.curves), length(app.measurement.curves(1).xData));
                        y = zeros(length(app.measurement.curves), length(app.measurement.curves(1).yData));
                        scans = cell(1,length(app.measurement.curves));
                        %Get the data from all the scans in the Cyclic Voltammetry measurement
                        for i = 1:length(app.measurement.curves)
                            x(i,:) = app.measurement.curves(i).xData;
                            y(i,:) = app.measurement.curves(i).yData;
                            scans{i} = [app.measurement.name ' scan ' num2str(i)];
                        end

                        %Plot the data
                        hold(app.UIAxes3,'on')
                        CV_spectra(n).CVdata(p).data = [x' y'];
                        plot(app.UIAxes3,x',y'), title(app.measurement.type), xlabel(app.measurement.curves(1).xUnit), ylabel(app.measurement.curves(1).yUnit), legend(scans);
                        %save data

                        data(n).CV_data(p).voltage = x;
                        data(n).CV_data(p).current = y;

                        clear x y
                        pause(0.1)
                    end
                    app.comm.CellOn = false;

                    % Disconnect and reconnect to potentiostat
                    app.comm.Disconnect();

                    deviceList = GetConnectedDevices(); %see function help for scanning bluetooth and serial devices
                    nDevices = deviceList.Count; %number of found devices
                    app.TextArea.Value = ([app.TextArea.Value' newline num2str(nDevices) ' device(s) connected:']);
                    scroll(app.TextArea, 'bottom')

                    %Display connected devices
                    for i=0:nDevices-1
                        disp([num2str(i+1) '. ' char(deviceList.Item(i).ToString())]);
                    end

                    %Prompt device to open a connection to
                    selectedDevice = 1;

                    %Open a connection to the specified device
                    selectedDevice = selectedDevice - 1;
                    app.deviceName = char(deviceList.Item(selectedDevice).ToString())
                    app.TextArea.Value = ([app.TextArea.Value' 'Attempting to open a connection with ' app.deviceName newline]);
                    scroll(app.TextArea, 'bottom')
                    app.comm = OpenConnection(deviceList.Item(selectedDevice));
                    if(app.comm == false) %Check whether the connection was succesfully opened
                        app.TextArea.Value = ([app.TextArea.Value' 'Error while opening a connection to ' app.deviceName '. Please check whether the specified device' newline ' is a PalmSens or Emstat device, remove the usb and plug it back in, and try to open the connection again' newline]);
                        scroll(app.TextArea, 'bottom')
                        return;
                    end

                    % end of CVs

                    % Absorption spectroelectrochemistry and chronoamperometry

                    spec_V_steps = data(n).Abs_Start:data(n).Abs_Step:data(n).Abs_End;
                    % spec_V_steps_back = data(n).Spec_End:-data(n).Spec_Step:data(n).Spec_Start;

                    app.comm.CellOn = true;
                    app.TextArea.Value = ([app.TextArea.Value' 'Cell is On'])
                    scroll(app.TextArea, 'bottom')

                    savecounter = 1;

                    for q=1:length(spec_V_steps)
                        if spec_V_steps(q) == 0 % fixes the bug where matlab crashes if we set V to 0
                            spec_V_steps(q) = 0.0001;
                        end

                        % Start chronoamperometry (doping)

                        cd 'C:\Users\cgbis\Desktop\palmsense_new'

                        test = 1;

                        if test == 1

                            voltage_text = sprintf('Setting Voltage to %.2f V', spec_V_steps(q));
                            app.TextArea.Value = voltage_text;
                            scroll(app.TextArea, 'bottom')

                            app.method = PalmSens.Techniques.AmperometricDetection();
                            app.method.BeginPotential = spec_V_steps(q); %app.EquilibrationVoltageVEditField.Value;
                            app.method.EquilibrationTime = 0;
                            app.method.RunTime = data(n).Abs_Wait;

                            app.m = Measurement(app.comm);
                            app.m.dispInCommandWindow = true; %Displays status information in the command window
                            app.m.dispInPlot = true; %Plots measurement data in realtime


                            % Equilibration

                            %Turn on the cell
                            app.comm.CellOn = true;
                            app.TextArea.Value = ([app.TextArea.Value' 'Cell is On'])
                            scroll(app.TextArea, 'bottom')

                            app.comm.Potential = data(n).Abs_Dedope;
                            app.TextArea.Value = ([app.TextArea.Value' 'Starting Equilibration']);
                            scroll(app.TextArea, 'bottom')
                            pause(5)
                            app.TextArea.Value = ([app.TextArea.Value' 'Equilibration Finished']);
                            scroll(app.TextArea, 'bottom')

                            app.m.New(app.method)

                            while(app.m.inMeasurement) %pauses until measurement is over
                                pause(0.01);
                            end

                            app.measurement = app.m.measurement;

                            %preallocate the x & y axis and the legend
                            x = zeros(length(app.measurement.curves), length(app.measurement.curves(1).xData));
                            y = zeros(length(app.measurement.curves), length(app.measurement.curves(1).yData));
                            scans = cell(1,length(app.measurement.curves));
                            %Get the data from all the scans in the chronoamperometry measurement
                            for i = 1:length(app.measurement.curves)
                                x(i,:) = app.measurement.curves(i).xData;
                                y(i,:) = app.measurement.curves(i).yData;
                                scans{i} = [app.measurement.name ' scan ' num2str(i)];
                            end

                            % Save the data
                            data(n).chrono_doping_data(savecounter).time = x;
                            data(n).chrono_doping_data(savecounter).current = y;

                            %Plot the data
                            hold(app.UIAxes3_2,'on')
                            plot(app.UIAxes3_2,x',y','Color',app.color_gradient(q,length(spec_V_steps))), title(app.measurement.type), xlabel(app.measurement.curves(1).xUnit), ylabel(app.measurement.curves(1).yUnit), legend(scans);
                            xlim([-0.1 inf])

                            clear x y
                            pause(0.1)
                            app.comm.CellOn = false;

                            % Disconnect and reconnect to potentiostat
                            app.comm.Disconnect();

                            deviceList = GetConnectedDevices(); %see function help for scanning bluetooth and serial devices
                            nDevices = deviceList.Count; %number of found devices
                            app.TextArea.Value = ([app.TextArea.Value' newline num2str(nDevices) ' device(s) connected:']);
                            scroll(app.TextArea, 'bottom')

                            %Display connected devices
                            for i=0:nDevices-1
                                disp([num2str(i+1) '. ' char(deviceList.Item(i).ToString())]);
                            end

                            %Prompt device to open a connection to
                            selectedDevice = 1;

                            %Open a connection to the specified device
                            selectedDevice = selectedDevice - 1;
                            app.deviceName = char(deviceList.Item(selectedDevice).ToString())
                            app.TextArea.Value = ([app.TextArea.Value' 'Attempting to open a connection with ' app.deviceName newline]);
                            scroll(app.TextArea, 'bottom')
                            app.comm = OpenConnection(deviceList.Item(selectedDevice));
                            if(app.comm == false) %Check whether the connection was succesfully opened
                                app.TextArea.Value = ([app.TextArea.Value' 'Error while opening a connection to ' app.deviceName '. Please check whether the specified device' newline ' is a PalmSens or Emstat device, remove the usb and plug it back in, and try to open the connection again' newline]);
                                scroll(app.TextArea, 'bottom')
                                return;
                            end
                        end

                        % End chronoamperometry (doping)

                        % Collecting Spectrum after doping
                        if test == 1
                            writeDigitalPin(app.a,'D11',1) % Opens the lamp shutter
                            app.comm.Potential = spec_V_steps(q)
                            pause(5)
                            app.TextArea.Value = ([app.TextArea.Value' 'Equilibration Finished']);
                            scroll(app.TextArea, 'bottom')
                            spectralData_ss = 0;
                            wavelengths = invoke(app.spectrometerObj, 'getWavelengths', spectrometerIndex, channelIndex);
                            for r = 1:Nspectra
                                spectrum_temp = invoke(app.spectrometerObj, 'getSpectrum', spectrometerIndex);
                                spectralData_ss = spectralData_ss + spectrum_temp;
                            end
                            abs_spectrum = -log(medfilt1(spectralData_ss)./medfilt1(spectralData_bg));
                            plot(app.UIAxes2,wavelengths,abs_spectrum,'Color',app.color_gradient(q,length(spec_V_steps)))
                            xlim(app.UIAxes2,[400 900])
                            hold(app.UIAxes2, 'on')

                            % Saving data

                            data(n).Abs(savecounter).voltage = spec_V_steps(q);
                            data(n).Abs(savecounter).spectrum = abs_spectrum;
                            writeDigitalPin(app.a,'D11',0) % Closes the lamp shutter
                        end

                        % Start chronoamperometry (dedoping)

                        cd 'C:\Users\cgbis\Desktop\palmsense_new'

                        test = 1;

                        if test == 1
                            voltage_text = sprintf('Setting Voltage to %.2f V', data(n).Abs_Dedope);
                            app.TextArea.Value = voltage_text;
                            scroll(app.TextArea, 'bottom')

                            app.method = PalmSens.Techniques.AmperometricDetection();
                            app.method.BeginPotential = data(n).Abs_Dedope; %app.EquilibrationVoltageVEditField.Value;
                            app.method.EquilibrationTime = 0;
                            app.method.RunTime = data(n).Abs_Wait;

                            app.m = Measurement(app.comm);
                            app.m.dispInCommandWindow = true; %Displays status information in the command window
                            app.m.dispInPlot = true; %Plots measurement data in realtime


                            % Equilibration

                            %Turn on the cell
                            app.comm.CellOn = true;
                            app.TextArea.Value = ([app.TextArea.Value' 'Cell is On'])
                            scroll(app.TextArea, 'bottom')

                            app.comm.Potential = spec_V_steps(q);
                            app.TextArea.Value = ([app.TextArea.Value' 'Starting Equilibration']);
                            scroll(app.TextArea, 'bottom')
                            pause(5)
                            app.TextArea.Value = ([app.TextArea.Value' 'Equilibration Finished']);
                            scroll(app.TextArea, 'bottom')

                            app.m.New(app.method)

                            while(app.m.inMeasurement) %pauses until measurement is over
                                pause(0.01);
                            end

                            app.measurement = app.m.measurement;

                            % Preallocate the x & y axis and the legend
                            x = zeros(length(app.measurement.curves), length(app.measurement.curves(1).xData));
                            y = zeros(length(app.measurement.curves), length(app.measurement.curves(1).yData));
                            scans = cell(1,length(app.measurement.curves));
                            % Get the data from all the scans in the chronoamperometry measurement
                            for i = 1:length(app.measurement.curves)
                                x(i,:) = app.measurement.curves(i).xData;
                                y(i,:) = app.measurement.curves(i).yData;
                                scans{i} = [app.measurement.name ' scan ' num2str(i)];
                            end

                            % Save the data
                            data(n).chrono_dedoping_data(savecounter).time = x;
                            data(n).chrono_dedoping_data(savecounter).current = y;

                            % Plot the data
                            hold(app.UIAxes3_3,'on')
                            plot(app.UIAxes3_3,x',y','Color',app.color_gradient(q,length(spec_V_steps))), title(app.measurement.type), xlabel(app.measurement.curves(1).xUnit), ylabel(app.measurement.curves(1).yUnit), legend(scans);
                            xlim([-0.1 inf])

                            clear x y
                            pause(0.1)
                            app.comm.CellOn = false;

                            % Disconnect and reconnect to potentiostat
                            app.comm.Disconnect();

                            deviceList = GetConnectedDevices(); %see function help for scanning bluetooth and serial devices
                            nDevices = deviceList.Count; %number of found devices
                            app.TextArea.Value = ([app.TextArea.Value' newline num2str(nDevices) ' device(s) connected:']);
                            scroll(app.TextArea, 'bottom')

                            %Display connected devices
                            for i=0:nDevices-1
                                disp([num2str(i+1) '. ' char(deviceList.Item(i).ToString())]);
                            end

                            %Prompt device to open a connection to
                            selectedDevice = 1;

                            %Open a connection to the specified device
                            selectedDevice = selectedDevice - 1;
                            app.deviceName = char(deviceList.Item(selectedDevice).ToString())
                            app.TextArea.Value = ([app.TextArea.Value' 'Attempting to open a connection with ' app.deviceName newline]);
                            scroll(app.TextArea, 'bottom')
                            app.comm = OpenConnection(deviceList.Item(selectedDevice));
                            if(app.comm == false) %Check whether the connection was succesfully opened
                                app.TextArea.Value = ([app.TextArea.Value' 'Error while opening a connection to ' app.deviceName '. Please check whether the specified device' newline ' is a PalmSens or Emstat device, remove the usb and plug it back in, and try to open the connection again' newline]);
                                scroll(app.TextArea, 'bottom')
                                return;
                            end
                        end

                        % End chronoamperometry (dedoping)

                        % Collecting Spectrum after dedoping
                        if test == 1
                            writeDigitalPin(app.a,'D11',1) % Opens the lamp shutter
                            app.comm.Potential = data(n).Abs_Dedope;
                            voltage_text = sprintf('Setting Voltage to %d V', data(n).Abs_Dedope);
                            app.TextArea.Value = voltage_text;
                            scroll(app.TextArea, 'bottom')
                            pause(5)
                            app.TextArea.Value = ([app.TextArea.Value' 'Equilibration Finished']);
                            scroll(app.TextArea, 'bottom')
                            spectralData_ss = 0;
                            wavelengths = invoke(app.spectrometerObj, 'getWavelengths', spectrometerIndex, channelIndex);
                            for r = 1:Nspectra
                                spectrum_temp = invoke(app.spectrometerObj, 'getSpectrum', spectrometerIndex);
                                spectralData_ss = spectralData_ss + spectrum_temp;
                            end
                            abs_dedope_spectrum = -log(medfilt1(spectralData_ss)./medfilt1(spectralData_bg));
                            plot(app.UIAxes2_2,wavelengths,abs_dedope_spectrum,'Color',app.color_gradient(q,length(spec_V_steps)))
                            xlim(app.UIAxes2_2,[400 900])
                            hold(app.UIAxes2_2, 'on')

                            % Saving data
                            data(n).Abs(savecounter).dedopevoltage = data(n).Abs_Dedope;
                            data(n).Abs(savecounter).dedope_spectrum = abs_dedope_spectrum;
                            writeDigitalPin(app.a,'D11',0) % Closes the lamp shutter
                        end
                        savecounter = savecounter+1; % Ensures that files are not overwritten as they are saved
                    end

                    pause(5)

                    % End of absorption spectroelectrochemistry

                    writeDigitalPin(app.a,'D6',1)
                    app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Sent'])
                    scroll(app.TextArea, 'bottom')
                    pause(5)
                    writeDigitalPin(app.a,'D6',0)
                    app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Off'])
                    scroll(app.TextArea, 'bottom')

                    img = [];
                    counter = 0;

                    while readDigitalPin(app.a,'D7') < 1 && counter <= 1800
                        countertext = sprintf('Looking for Signal from Robot (Counter at %d)', counter)
                        app.TextArea.Value = ([app.TextArea.Value' newline countertext])
                        scroll(app.TextArea, 'bottom')
                        pause(0.1)
                        img1 = snapshot(cam2);
                        img = cat(2,img,img1);
                        counter = counter + 1;
                    end

                    if counter > 1800
                        ImageLength = length(img);
                        ImageFrameCount = ImageLength / 640;
                        ImageFrames = cell(ImageFrameCount,1);
                        for n = 1:ImageFrameCount
                            ImageFrames{n} = img(:,(n-1)*640+1:n*640,:);
                        end
                        writerObj = VideoWriter(sprintf('RobotFailVideo %s',today('datetime')),'MPEG-4');
                        writerObj.FrameRate = 10;
                        open(writerObj);
                        for z = 1:length(ImageFrames)
                            frame = im2frame(ImageFrames{z});
                            for x = 0:0.1
                                writeVideo(writerObj,frame);
                            end
                        end
                        close(writerObj);
                        answer = questdlg('GAME OVER?','Yes','No');
                    end

                    clear img
                    clear counter

                    app.TextArea.Value = ([app.TextArea.Value' newline 'Received Signal from Robot'])
                    scroll(app.TextArea, 'bottom')

                    pause(5)

                    cam1 = webcam(1);
                    cam1.Resolution = '1280x720';
                    img = snapshot(cam1);
                    rect = [465.5100    8.5100  458.9800  604.9800];

                    img1=imcrop(img,rect);
                    img3 = flipud(img1);

                    data(n).imageafterAbs = img3;

                    imshow(img3,'Parent',app.UIAxes4_4)

                    pause(5)

                    writeDigitalPin(app.a,'D6',1)
                    app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Sent'])
                    scroll(app.TextArea, 'bottom')
                    pause(5)
                    writeDigitalPin(app.a,'D6',0)
                    app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Off'])
                    scroll(app.TextArea, 'bottom')

                    img = [];
                    counter = 0;

                    while readDigitalPin(app.a,'D7') < 1 && counter <= 1800
                        countertext = sprintf('Looking for Signal from Robot (Counter at %d)', counter)
                        app.TextArea.Value = ([app.TextArea.Value' newline countertext])
                        scroll(app.TextArea, 'bottom')
                        pause(0.1)
                        img1 = snapshot(cam2);
                        img = cat(2,img,img1);
                        counter = counter + 1;
                    end

                    if counter > 1800
                        ImageLength = length(img);
                        ImageFrameCount = ImageLength / 640;
                        ImageFrames = cell(ImageFrameCount,1);
                        for n = 1:ImageFrameCount
                            ImageFrames{n} = img(:,(n-1)*640+1:n*640,:);
                        end
                        writerObj = VideoWriter(sprintf('RobotFailVideo %s',today('datetime')),'MPEG-4');
                        writerObj.FrameRate = 10;
                        open(writerObj);
                        for z = 1:length(ImageFrames)
                            frame = im2frame(ImageFrames{z});
                            for x = 0:0.1
                                writeVideo(writerObj,frame);
                            end
                        end
                        close(writerObj);
                        answer = questdlg('GAME OVER?','Yes','No');
                    end

                    clear img
                    clear counter

                    app.TextArea.Value = ([app.TextArea.Value' newline 'Received Signal from Robot'])
                    scroll(app.TextArea, 'bottom')

                    pause(5)

                    cam1 = webcam(1);
                    cam1.Resolution = '1280x720';
                    img = snapshot(cam1);
                    rect = [465.5100    8.5100  458.9800  604.9800];

                    img1=imcrop(img,rect);
                    img3 = flipud(img1);

                    data(n).imageafterRinse = img3;

                    imshow(img3,'Parent',app.UIAxes4_5)

                    pause(5)

                    writeDigitalPin(app.a,'D6',1)
                    app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Sent'])
                    scroll(app.TextArea, 'bottom')
                    pause(5)
                    writeDigitalPin(app.a,'D6',0)
                    app.TextArea.Value = ([app.TextArea.Value' newline 'Signal Off'])
                    scroll(app.TextArea, 'bottom')

                    % Save after every sample
                    cd 'C:\Users\cgbis\Desktop'
                    save('saveddata.mat', 'data')

                    cla(app.UIAxes2)
                    cla(app.UIAxes2_2)
                    cla(app.UIAxes3_2)
                    cla(app.UIAxes3)
                    cla(app.UIAxes3_3)
                    cla(app.UIAxes3_4)
                    cla(app.UIAxes4_3)
                    cla(app.UIAxes4_4)
                    cla(app.UIAxes4_5)

                    hold(app.UIAxes3,'on')
                    hold(app.UIAxes3_4,'on')

                    for k = 1:n
                        plot(app.UIAxes3,data(k).CV_data(p).voltage,data(k).CV_data(p).current)
                        plot(app.UIAxes3_4,data(k).chrono_doping_data(end).time,data(k).chrono_doping_data(end).current)
                    end
                end
            end
            app.TextArea.Value = ([app.TextArea.Value' newline 'Run Complete!'])
            scroll(app.TextArea, 'bottom')

            % Retrieve the start time from AppData
            startTime = getappdata(0,'AppStartTime');

            % Calculate the elapsed time
            elapsedTime = toc(startTime);
            elapsedTimeMinutes = elapsedTime / 60;

            % Ensure the existing value of the TextArea is in the correct format
            existingValue = app.TextArea.Value;

            % Convert the existing value to a cell array of character vectors if it's not already
            if ischar(existingValue)
                existingValue = {existingValue};
            elseif isstring(existingValue)
                existingValue = cellstr(existingValue);
            end

            % Format the new message
            newMessage = sprintf('Total execution time: %.2f minutes\n', elapsedTimeMinutes);

            % Append the new message to the existing value
            updatedValue = [existingValue; {newMessage}];

            % Set the updated value back to the TextArea
            app.TextArea.Value = updatedValue;

            data(1).expTime = elapsedTimeMinutes;

            cd 'C:\Users\cgbis\Desktop'
            save('saveddata.mat', 'data')

            app.TextArea.Value = ([app.TextArea.Value' newline 'Data Saved!'])
            scroll(app.TextArea, 'bottom')

            app.TextArea.Value = ([app.TextArea.Value' newline 'Remember to turn off the UV-Vis lamp!'])
            scroll(app.TextArea, 'bottom')

        end

        % Button pushed function: ConnecttoSpectrometerButton
        function ConnecttoSpectrometerButtonPushed(app, event)
            app.spectrometerObj = icdevice('OceanOptics_OmniDriver.mdd')
            badconnect = 0;

            try
                connect(app.spectrometerObj);
            catch
                app.TextArea.Value = ([app.TextArea.Value' 'Ocean Optics Status: No spectrometer connected.'])
                scroll(app.TextArea, 'bottom')
                set(app.DisconnectButton_4,'enable','off');
                set(app.ConnecttoSpectrometerButton,'enable','on');
                badconnect = 1;
            end

            if badconnect == 0
                app.TextArea.Value = ([app.TextArea.Value' 'Ocean Optics Status: Connected'])
                scroll(app.TextArea, 'bottom')

                set(app.DisconnectButton_4,'enable','on');
                set(app.ConnecttoSpectrometerButton,'enable','off');
                %set(app.CollectSpectrumButton,'enable','on');
            end
            set(app.StartRobotButton, 'Enable', 'on')
        end

        % Button pushed function: DisconnectButton_4
        function DisconnectButton_4Pushed(app, event)
            disconnect(app.spectrometerObj);
            app.TextArea.Value = ([app.TextArea.Value' 'Ocean Optics Status: Disconnected'])
            scroll(app.TextArea, 'bottom')
            set(app.DisconnectButton_4,'enable','off');
            set(app.ConnecttoSpectrometerButton,'enable','on');
        end

        % Button pushed function: CollectSpectrumButton
        function CollectSpectrumButtonPushed(app, event)
            set(app.CollectSpectrumButton,'Enable','off')
            pause(0.1)

            app.TextArea.Value = ([app.TextArea.Value' 'Collecting Spectrum...']);
            scroll(app.TextArea, 'bottom')
            % integration time for sensor.
            integrationTime = 5000;
            % Spectrometer index to use (first spectrometer by default).
            spectrometerIndex = 0;
            % Channel index to use (first channel by default).
            channelIndex = 0;
            % Enable flag.
            enable = 1;

            % Set integration time.
            invoke(app.spectrometerObj, 'setIntegrationTime', spectrometerIndex, channelIndex, integrationTime);
            % Enable correct for detector non-linearity.
            invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', spectrometerIndex, channelIndex, enable);
            % Enable correct for electrical dark.
            invoke(app.spectrometerObj, 'setCorrectForElectricalDark', spectrometerIndex, channelIndex, enable);

            wavelengths = invoke(app.spectrometerObj, 'getWavelengths', spectrometerIndex, channelIndex);
            % Get the wavelengths of the first spectrometer and save them in a double
            % array.
            spectralData_ss = invoke(app.spectrometerObj, 'getSpectrum', spectrometerIndex);

            abs_spectrum = spectralData_ss

            plot(app.UIAxes2,wavelengths,abs_spectrum)
            xlim(app.UIAxes2,[420 800])

            set(app.CollectSpectrumButton,'Enable','on')
        end

        % Callback function
        function CollectLampSpectrumButtonPushed(app, event)
            set(app.CollectSpectrumButton,'Enable','on')
            pause(0.1)

            app.TextArea.Value = ([app.TextArea.Value' 'Collecting Spectrum...']);
            scroll(app.TextArea, 'bottom')
            % integration time for sensor.
            integrationTime = app.IntegrationtimesEditField_2.Value*100000;
            % Spectrometer index to use (first spectrometer by default).
            spectrometerIndex = 0;
            % Channel index to use (first channel by default).
            channelIndex = 0;
            % Enable flag.
            enable = 1;

            % Set integration time.
            invoke(app.spectrometerObj, 'setIntegrationTime', spectrometerIndex, channelIndex, integrationTime);
            % Enable correct for detector non-linearity.
            invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', spectrometerIndex, channelIndex, enable);
            % Enable correct for electrical dark.
            invoke(app.spectrometerObj, 'setCorrectForElectricalDark', spectrometerIndex, channelIndex, enable);

            wavelengths = invoke(app.spectrometerObj, 'getWavelengths', spectrometerIndex, channelIndex);
            % Get the wavelengths of the first spectrometer and save them in a double
            % array.
            app.lampspectrum = invoke(app.spectrometerObj, 'getSpectrum', spectrometerIndex);
            plot(app.UIAxes2,wavelengths,app.lampspectrum )
            xlim(app.UIAxes2,[420 800])
        end

        % Callback function
        function CollectDarkSpectrumButtonPushed(app, event)
            pause(0.1)

            app.TextArea.Value = ([app.TextArea.Value' 'Collecting Spectrum...']);
            scroll(app.TextArea, 'bottom')
            % integration time for sensor.
            integrationTime = app.IntegrationtimesEditField_2.Value*100000;
            % Spectrometer index to use (first spectrometer by default).
            spectrometerIndex = 0;
            % Channel index to use (first channel by default).
            channelIndex = 0;
            % Enable flag.
            enable = 1;

            % Set integration time.
            invoke(app.spectrometerObj, 'setIntegrationTime', spectrometerIndex, channelIndex, integrationTime);
            % Enable correct for detector non-linearity.
            invoke(app.spectrometerObj, 'setCorrectForDetectorNonlinearity', spectrometerIndex, channelIndex, enable);
            % Enable correct for electrical dark.
            invoke(app.spectrometerObj, 'setCorrectForElectricalDark', spectrometerIndex, channelIndex, enable);

            wavelengths = invoke(app.spectrometerObj, 'getWavelengths', spectrometerIndex, channelIndex);
            % Get the wavelengths of the first spectrometer and save them in a double
            % array.
            app.darkspectrum = invoke(app.spectrometerObj, 'getSpectrum', spectrometerIndex);
        end

        % Button pushed function: ConnecttoPotentiostatButton
        function ConnecttoPotentiostatButtonPushed2(app, event)
            set(app.ConnecttoPotentiostatButton, 'Enable', 'off')


            cd 'C:\Users\cgbis\Desktop\palmsense_new'

            PSSDK = LoadPSSDK();

            app.TextArea.Value = 'Initiating program...';

            if(PSSDK == false)
                app.TextArea.Value = 'Error while adding the PalmSens SDK to the workspace. Please resolve before continuing.';
                scroll(app.TextArea, 'bottom')
                set(app.ConnecttoPotentiostatButton, 'Enable', 'on')
                return;
            end

            deviceList = GetConnectedDevices(); %see function help for scanning bluetooth and serial devices
            nDevices = deviceList.Count; %number of found devices
            app.TextArea.Value = ([app.TextArea.Value' newline num2str(nDevices) ' device(s) connected:']);
            scroll(app.TextArea, 'bottom')

            %Display connected devices
            for i=0:nDevices-1
                disp([num2str(i+1) '. ' char(deviceList.Item(i).ToString())]);
            end

            %Prompt device to open a connection to
            selectedDevice = 1;

            %Open a connection to the specified device
            selectedDevice = selectedDevice - 1;
            app.deviceName = char(deviceList.Item(selectedDevice).ToString())
            app.TextArea.Value = ([app.TextArea.Value' 'Attempting to open a connection with ' app.deviceName newline]);
            scroll(app.TextArea, 'bottom')
            app.comm = OpenConnection(deviceList.Item(selectedDevice));
            if(app.comm == false) %Check whether the connection was succesfully opened
                app.TextArea.Value = ([app.TextArea.Value' 'Error while opening a connection to ' app.deviceName '. Please check whether the specified device' newline ' is a PalmSens or Emstat device, remove the usb and plug it back in, and try to open the connection again' newline]);
                scroll(app.TextArea, 'bottom')
                set(app.ConnecttoPotentiostatButton, 'Enable', 'on')
                return;
            end

            app.TextArea.Value = ([app.TextArea.Value' 'Succesfully connected to ' app.deviceName]);
            scroll(app.TextArea, 'bottom')

            set(app.DisconnectButton_5, 'Enable', 'on')
            set(app.StartRobotButton, 'Enable', 'on')
        end

        % Button pushed function: DisconnectButton_5
        function DisconnectButton_5Pushed(app, event)
            set(app.ConnecttoPotentiostatButton, 'Enable', 'on')

            app.TextArea.Value = ([app.TextArea.Value' newline 'Attempting to disconnect from ' app.deviceName]);
            scroll(app.TextArea, 'bottom')
            try
                app.comm.Disconnect();
                app.TextArea.Value = ([app.TextArea.Value' newline 'Succesfully closed the connection to ' app.deviceName]);
                scroll(app.TextArea, 'bottom')
            catch
                app.TextArea.Value = ([app.TextArea.Value' newline 'An error occured while trying to disconnect from ' app.deviceName]);
                scroll(app.TextArea, 'bottom')
            end

            set(app.DisconnectButton_5, 'Enable', 'off')
            set(app.StartRobotButton, 'Enable', 'off')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes4_3 = uiaxes(app.UIFigure);
            title(app.UIAxes4_3, 'Sample Before Absorption')
            zlabel(app.UIAxes4_3, 'Z')
            app.UIAxes4_3.XTick = [];
            app.UIAxes4_3.XTickLabel = '';
            app.UIAxes4_3.YTick = [];
            app.UIAxes4_3.YTickLabel = '';
            app.UIAxes4_3.ZTick = [];
            app.UIAxes4_4 = uiaxes(app.UIFigure);
            title(app.UIAxes4_4, 'Sample After Absorption')
            zlabel(app.UIAxes4_4, 'Z')
            app.UIAxes4_4.XTick = [];
            app.UIAxes4_4.XTickLabel = '';
            app.UIAxes4_4.YTick = [];
            app.UIAxes4_4.YTickLabel = '';
            app.UIAxes4_4.ZTick = [];
            app.UIAxes4_5 = uiaxes(app.UIFigure);
            title(app.UIAxes4_5, 'Sample After Rinsing')
            zlabel(app.UIAxes4_5, 'Z')
            app.UIAxes4_5.XTick = [];
            app.UIAxes4_5.XTickLabel = '';
            app.UIAxes4_5.YTick = [];
            app.UIAxes4_5.YTickLabel = '';
            app.UIAxes4_5.ZTick = [];
            app.ConnectionsPanel = uipanel(app.UIFigure);
            app.ConnectionsPanel.Title = 'Connections';
            app.ConnecttoArduinoButton = uibutton(app.ConnectionsPanel, 'push');
            app.ConnecttoArduinoButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoArduinoButtonPushed, true);
            app.ConnecttoArduinoButton.Text = 'Connect to Arduino';
            app.ConnecttoSpectrometerButton = uibutton(app.ConnectionsPanel, 'push');
            app.ConnecttoSpectrometerButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoSpectrometerButtonPushed, true);
            app.ConnecttoSpectrometerButton.Text = 'Connect to Spectrometer';
            app.DisconnectButton = uibutton(app.ConnectionsPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Enable = 'off';
            app.DisconnectButton.Text = 'Disconnect';
            app.DisconnectButton_4 = uibutton(app.ConnectionsPanel, 'push');
            app.DisconnectButton_4.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButton_4Pushed, true);
            app.DisconnectButton_4.Enable = 'off';
            app.DisconnectButton_4.Text = 'Disconnect';
            app.ConnecttoPotentiostatButton = uibutton(app.ConnectionsPanel, 'push');
            app.ConnecttoPotentiostatButton.ButtonPushedFcn = createCallbackFcn(app, @ConnecttoPotentiostatButtonPushed2, true);
            app.ConnecttoPotentiostatButton.Text = 'Connect to Potentiostat';
            app.DisconnectButton_5 = uibutton(app.ConnectionsPanel, 'push');
            app.DisconnectButton_5.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButton_5Pushed, true);
            app.DisconnectButton_5.Enable = 'off';
            app.DisconnectButton_5.Text = 'Disconnect';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.StartRobotButton = uibutton(app.ControlPanel, 'push');
            app.StartRobotButton.ButtonPushedFcn = createCallbackFcn(app, @StartRobotButtonPushed, true);
            app.StartRobotButton.Enable = 'off';
            app.StartRobotButton.Text = 'Start Robot*';
            app.CollectSpectrumButton = uibutton(app.ControlPanel, 'push');
            app.CollectSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @CollectSpectrumButtonPushed, true);
            app.CollectSpectrumButton.Text = 'Collect Spectrum';
            app.RobotStatusLabel = uilabel(app.UIFigure);
            app.RobotStatusLabel.Text = 'Robot Status';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.UVVisSpectroelectrochemistryDopingPanel = uipanel(app.UIFigure);
            app.UVVisSpectroelectrochemistryDopingPanel.Title = 'UV-Vis Spectroelectrochemistry (Doping)';
            app.UIAxes2 = uiaxes(app.UVVisSpectroelectrochemistryDopingPanel);
            xlabel(app.UIAxes2, 'wavelength (nm)')
            ylabel(app.UIAxes2, 'Absorbance')
            zlabel(app.UIAxes2, 'Z')
            app.CyclicVoltammetryPanel = uipanel(app.UIFigure);
            app.CyclicVoltammetryPanel.Title = 'Cyclic Voltammetry';
            app.UIAxes3 = uiaxes(app.CyclicVoltammetryPanel);
            xlabel(app.UIAxes3, 'Voltage (V vs. Ag/AgCl)')
            ylabel(app.UIAxes3, 'Current (A)')
            zlabel(app.UIAxes3, 'Z')
            app.StatusPanel = uipanel(app.UIFigure);
            app.StatusPanel.Title = 'Status';
            app.CurrentPolymerEditFieldLabel = uilabel(app.StatusPanel);
            app.CurrentPolymerEditFieldLabel.Text = 'Current Polymer';
            app.CurrentPolymerEditField = uieditfield(app.StatusPanel, 'text');
            app.CurrentPolymerEditField.Editable = 'off';
            app.CurrentElectrolyteEditFieldLabel = uilabel(app.StatusPanel);
            app.CurrentElectrolyteEditFieldLabel.Text = 'Current Electrolyte';
            app.CurrentElectrolyteEditField = uieditfield(app.StatusPanel, 'text');
            app.CurrentElectrolyteEditField.Editable = 'off';
            app.SampleNumberEditFieldLabel = uilabel(app.StatusPanel);
            app.SampleNumberEditFieldLabel.Text = 'Sample Number';
            app.SampleNumberEditField = uieditfield(app.StatusPanel, 'text');
            app.SampleNumberEditField.Editable = 'off';
            app.ChronoamperometryDopingPanel = uipanel(app.UIFigure);
            app.ChronoamperometryDopingPanel.Title = 'Chronoamperometry (Doping)';
            app.UIAxes3_2 = uiaxes(app.ChronoamperometryDopingPanel);
            xlabel(app.UIAxes3_2, 'Time (s)')
            ylabel(app.UIAxes3_2, 'Current')
            zlabel(app.UIAxes3_2, 'Z')
            app.ChronoamperometryDedopingPanel = uipanel(app.UIFigure);
            app.ChronoamperometryDedopingPanel.Title = 'Chronoamperometry (Dedoping)';
            app.UIAxes3_3 = uiaxes(app.ChronoamperometryDedopingPanel);
            xlabel(app.UIAxes3_3, 'Time (s)')
            ylabel(app.UIAxes3_3, 'Current')
            zlabel(app.UIAxes3_3, 'Z')
            app.UVVisSpectroelectrochemistryDedopingPanel = uipanel(app.UIFigure);
            app.UVVisSpectroelectrochemistryDedopingPanel.Title = 'UV-Vis Spectroelectrochemistry (Dedoping)';
            app.UIAxes2_2 = uiaxes(app.UVVisSpectroelectrochemistryDedopingPanel);
            xlabel(app.UIAxes2_2, 'wavelength (nm)')
            ylabel(app.UIAxes2_2, 'Absorbance')
            zlabel(app.UIAxes2_2, 'Z')
            app.ChronoamperometryPanel = uipanel(app.UIFigure);
            app.ChronoamperometryPanel.Title = 'Chronoamperometry';
            app.UIAxes3_4 = uiaxes(app.ChronoamperometryPanel);
            xlabel(app.UIAxes3_4, 'Time (s)')
            ylabel(app.UIAxes3_4, 'Current')
            zlabel(app.UIAxes3_4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_spectroelectrochemistry_Abs_chrono_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end